classdef File_Outputs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        VehicleDataPanel         matlab.ui.container.Panel
        PresscalculatetodisplayCOEsLabel  matlab.ui.control.Label
        lEditField               matlab.ui.control.EditField
        lEditFieldLabel          matlab.ui.control.Label
        piEditField              matlab.ui.control.EditField
        piEditFieldLabel         matlab.ui.control.Label
        uEditField               matlab.ui.control.EditField
        uEditFieldLabel          matlab.ui.control.Label
        vEditField               matlab.ui.control.EditField
        vEditFieldLabel          matlab.ui.control.Label
        OMEGAEditField           matlab.ui.control.EditField
        OMEGAEditFieldLabel      matlab.ui.control.Label
        omegaEditField           matlab.ui.control.EditField
        omegaEditFieldLabel      matlab.ui.control.Label
        iEditField               matlab.ui.control.EditField
        iEditFieldLabel          matlab.ui.control.Label
        eEditField               matlab.ui.control.EditField
        eEditFieldLabel          matlab.ui.control.Label
        aEditField               matlab.ui.control.EditField
        aEditFieldLabel          matlab.ui.control.Label
        CalculateButton          matlab.ui.control.Button
        BrowseButton             matlab.ui.control.Button
        FileInputEditField       matlab.ui.control.EditField
        FileInputEditFieldLabel  matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        ExitButton               matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
        degreesLabel_7           matlab.ui.control.Label
        degreesLabel_6           matlab.ui.control.Label
        degreesLabel_5           matlab.ui.control.Label
        degreesLabel_4           matlab.ui.control.Label
        degreesLabel_3           matlab.ui.control.Label
        degreesLabel_2           matlab.ui.control.Label
        degreesLabel             matlab.ui.control.Label
        kmLabel                  matlab.ui.control.Label
        OrbitEditField           matlab.ui.control.EditField
        OrbitEditFieldLabel      matlab.ui.control.Label
        VehicleIDEditField       matlab.ui.control.EditField
        VehicleIDLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        FileInputPath = "";
        vehid;
        coeData;
            % Description
    end
    
    methods (Access = public)
        function updateCOEs(app)
            app.VehicleIDEditField.Value = int2str(app.vehid);
            app.aEditField.Value = app.coeData{1};
            app.eEditField.Value = app.coeData{2};
            app.iEditField.Value = app.coeData{3};
            app.omegaEditField.Value = app.coeData{4};
            app.OMEGAEditField.Value = app.coeData{5};
            app.vEditField.Value = app.coeData{6};
            app.uEditField.Value = app.coeData{7};
            app.piEditField.Value = app.coeData{8};
            app.lEditField.Value = app.coeData{9};
            app.OrbitEditField.Value = app.coeData{10};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'Close';
            quest = 'Do you wish to close and return to Manual/File Input Window?';
            message = questdlg(quest,'Warning','Close','Cancel',opts);
            switch message
                case 'Close'
                    closereq;
                    app.ManualFile_Input=ManualFile_Input();
                case 'Cancel'

            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'Close';
            quest = 'Do you wish to exit the program?';
            message = questdlg(quest,'Warning','Close','Cancel',opts);
            
            switch message
                case 'Close'
                    closereq;
                    quit;
                case 'Cancel'

            end
        end

        % Value changing function: VehicleIDEditField
        function VehicleIDEditFieldValueChanging(app, event)
           
        end

        % Value changed function: VehicleIDEditField
        function VehicleIDEditFieldValueChanged(app, event)
            app.inputs.vehid = app.VehicleIDEditField.Value;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,pathname]=uigetfile('*.');
            if isequal(filename,0)
                
            else
                app.FileInputPath = fullfile(pathname, filename);
                app.FileInputEditField.Value = app.FileInputPath;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [opened, vehID, R, V] = readFile(app.FileInputEditField.Value);
            app.vehid = vehID;

            if opened == 0
                % open gui that says 'Invalid file format or file is
                % already opened'
                fig = uifigure;
                uialert(fig,'Invalid file format or file is already opened','Invalid File');
            else
                % open gui that diplays outputs
                app.coeData = orbitCalc(R, V);
                app.updateCOEs();
            end

            % change values of 'edit fields

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveCOEs(app.coeData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VehicleDataPanel = uipanel(app.UIFigure);
            app.VehicleDataPanel.Title = 'Vehicle Data';
            app.VehicleIDLabel = uilabel(app.VehicleDataPanel);
            app.VehicleIDLabel.Text = 'Vehicle ID:';
            app.VehicleIDEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.VehicleIDEditField.ValueChangedFcn = createCallbackFcn(app, @VehicleIDEditFieldValueChanged, true);
            app.VehicleIDEditField.ValueChangingFcn = createCallbackFcn(app, @VehicleIDEditFieldValueChanging, true);
            app.OrbitEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.OrbitEditFieldLabel.Text = 'Orbit:';
            app.OrbitEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.kmLabel = uilabel(app.VehicleDataPanel);
            app.kmLabel.Text = 'km';
            app.degreesLabel = uilabel(app.VehicleDataPanel);
            app.degreesLabel.Text = 'degrees';
            app.degreesLabel_2 = uilabel(app.VehicleDataPanel);
            app.degreesLabel_2.Text = 'degrees';
            app.degreesLabel_3 = uilabel(app.VehicleDataPanel);
            app.degreesLabel_3.Text = 'degrees';
            app.degreesLabel_4 = uilabel(app.VehicleDataPanel);
            app.degreesLabel_4.Text = 'degrees';
            app.degreesLabel_5 = uilabel(app.VehicleDataPanel);
            app.degreesLabel_5.Text = 'degrees';
            app.degreesLabel_6 = uilabel(app.VehicleDataPanel);
            app.degreesLabel_6.Text = 'degrees';
            app.degreesLabel_7 = uilabel(app.VehicleDataPanel);
            app.degreesLabel_7.Text = 'degrees';
            app.BackButton = uibutton(app.VehicleDataPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitButton = uibutton(app.VehicleDataPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveButton = uibutton(app.VehicleDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileInputEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.FileInputEditFieldLabel.Text = 'File Input';
            app.FileInputEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.BrowseButton = uibutton(app.VehicleDataPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CalculateButton = uibutton(app.VehicleDataPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.aEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.eEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.eEditFieldLabel.Text = 'e:';
            app.eEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.iEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.iEditFieldLabel.Text = 'i:';
            app.iEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.omegaEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.omegaEditFieldLabel.Text = 'omega:';
            app.omegaEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.OMEGAEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.OMEGAEditFieldLabel.Text = 'OMEGA:';
            app.OMEGAEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.vEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.vEditFieldLabel.Text = 'v:';
            app.vEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.uEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.uEditFieldLabel.Text = 'u:';
            app.uEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.piEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.piEditFieldLabel.Text = 'pi:';
            app.piEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.lEditFieldLabel = uilabel(app.VehicleDataPanel);
            app.lEditFieldLabel.Text = 'l:';
            app.lEditField = uieditfield(app.VehicleDataPanel, 'text');
            app.PresscalculatetodisplayCOEsLabel = uilabel(app.VehicleDataPanel);
            app.PresscalculatetodisplayCOEsLabel.Text = 'Press calculate to display COE''s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = File_Outputs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end