classdef Manual_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ManualInputTab                matlab.ui.container.Tab
        degreessLabel_2               matlab.ui.control.Label
        daysLabel                     matlab.ui.control.Label
        hrsLabel                      matlab.ui.control.Label
        degreessLabel                 matlab.ui.control.Label
        kmsLabel                      matlab.ui.control.Label
        kmLabel_5                     matlab.ui.control.Label
        kmLabel_4                     matlab.ui.control.Label
        degreesLabel_15               matlab.ui.control.Label
        degreesLabel_14               matlab.ui.control.Label
        degreesLabel_13               matlab.ui.control.Label
        degreesLabel_12               matlab.ui.control.Label
        ExitButton_2                  matlab.ui.control.Button
        TOFEditField                  matlab.ui.control.NumericEditField
        TOFLabel                      matlab.ui.control.Label
        GSTEditField                  matlab.ui.control.NumericEditField
        GSTLabel                      matlab.ui.control.Label
        SiteNameEditField             matlab.ui.control.EditField
        SiteNameLabel                 matlab.ui.control.Label
        AzimuthChangeEditField        matlab.ui.control.NumericEditField
        AzimuthChangeLabel            matlab.ui.control.Label
        RangeEditField                matlab.ui.control.NumericEditField
        RangeLabel                    matlab.ui.control.Label
        ElevationEditField            matlab.ui.control.NumericEditField
        ElevationLabel                matlab.ui.control.Label
        ElevationChangeEditField      matlab.ui.control.NumericEditField
        ElevationChangeLabel          matlab.ui.control.Label
        AzimuthEditField              matlab.ui.control.NumericEditField
        AzimuthLabel                  matlab.ui.control.Label
        RangeChangeEditField          matlab.ui.control.NumericEditField
        RangeChangeLabel              matlab.ui.control.Label
        AltitudeEditField             matlab.ui.control.NumericEditField
        AltitudeLabel                 matlab.ui.control.Label
        LatitudeEditField             matlab.ui.control.NumericEditField
        LatitudeLabel                 matlab.ui.control.Label
        LongitudeEditField            matlab.ui.control.NumericEditField
        LongitudeLabel                matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        FileInputTab                  matlab.ui.container.Tab
        TimeEditField                 matlab.ui.control.EditField
        TimeLabel                     matlab.ui.control.Label
        secondsLabel                  matlab.ui.control.Label
        TOFoptionalEditField          matlab.ui.control.EditField
        TOFoptionalLabel              matlab.ui.control.Label
        lEditField                    matlab.ui.control.EditField
        lEditFieldLabel               matlab.ui.control.Label
        piEditField                   matlab.ui.control.EditField
        piEditFieldLabel              matlab.ui.control.Label
        uEditField                    matlab.ui.control.EditField
        uEditFieldLabel               matlab.ui.control.Label
        vEditField                    matlab.ui.control.EditField
        vEditFieldLabel               matlab.ui.control.Label
        OMEGAEditField                matlab.ui.control.EditField
        OMEGAEditFieldLabel           matlab.ui.control.Label
        omegaEditField                matlab.ui.control.EditField
        omegaEditFieldLabel           matlab.ui.control.Label
        iEditField                    matlab.ui.control.EditField
        iEditFieldLabel               matlab.ui.control.Label
        eEditField                    matlab.ui.control.EditField
        eEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.EditField
        aEditFieldLabel               matlab.ui.control.Label
        FileInputEditField            matlab.ui.control.EditField
        FileInputLabel                matlab.ui.control.Label
        OrbitEditField                matlab.ui.control.EditField
        OrbitEditFieldLabel           matlab.ui.control.Label
        VehicleIDEditField            matlab.ui.control.EditField
        VehicleIDEditFieldLabel       matlab.ui.control.Label
        PressCalculatetoDispalyOutputLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        degreesLabel_7                matlab.ui.control.Label
        degreesLabel_6                matlab.ui.control.Label
        degreesLabel_5                matlab.ui.control.Label
        degreesLabel_4                matlab.ui.control.Label
        degreesLabel_3                matlab.ui.control.Label
        degreesLabel_2                matlab.ui.control.Label
        degreesLabel                  matlab.ui.control.Label
        kmLabel                       matlab.ui.control.Label
        OutputTab                     matlab.ui.container.Tab
        SaveButton_2                  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        OrbitManeuverTab              matlab.ui.container.Tab
        kmLabel_8                     matlab.ui.control.Label
        degreesLabel_17               matlab.ui.control.Label
        degreesLabel_16               matlab.ui.control.Label
        TransferOrbitRadiusEditField  matlab.ui.control.NumericEditField
        TransferOrbitRadiusEditFieldLabel  matlab.ui.control.Label
        DetermineButton               matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        hrsLabel_7                    matlab.ui.control.Label
        kmsLabel_4                    matlab.ui.control.Label
        hrsLabel_6                    matlab.ui.control.Label
        kmLabel_7                     matlab.ui.control.Label
        kmLabel_6                     matlab.ui.control.Label
        hrsLabel_5                    matlab.ui.control.Label
        kmsLabel_3                    matlab.ui.control.Label
        hrsLabel_4                    matlab.ui.control.Label
        hrsLabel_3                    matlab.ui.control.Label
        kmsLabel_2                    matlab.ui.control.Label
        hrsLabel_2                    matlab.ui.control.Label
        OrbitTransferOptionsButtonGroup  matlab.ui.container.ButtonGroup
        EllipticButton                matlab.ui.control.RadioButton
        HohmannButton                 matlab.ui.control.RadioButton
        WaitTimeEditField_3           matlab.ui.control.EditField
        WaitTimeLabel_3               matlab.ui.control.Label
        DeltaVEditField_3             matlab.ui.control.EditField
        DeltaVLabel_3                 matlab.ui.control.Label
        TOFEditField_4                matlab.ui.control.EditField
        TOFLabel_4                    matlab.ui.control.Label
        OpTransferRadiusEditField_2   matlab.ui.control.EditField
        OptimalTransferRadiusLabel_2  matlab.ui.control.Label
        WaitTimeEditField_2           matlab.ui.control.EditField
        WaitTimeLabel_2               matlab.ui.control.Label
        WaitTimeEditField             matlab.ui.control.EditField
        WaitTimeLabel                 matlab.ui.control.Label
        DeltaVEditField_2             matlab.ui.control.EditField
        DeltaVLabel_2                 matlab.ui.control.Label
        TOFEditField_3                matlab.ui.control.EditField
        TOFLabel_3                    matlab.ui.control.Label
        DeltaVEditField               matlab.ui.control.EditField
        DeltaVLabel                   matlab.ui.control.Label
        TOFEditField_2                matlab.ui.control.EditField
        TOFLabel_2                    matlab.ui.control.Label
        UserConstrainedLabel          matlab.ui.control.Label
        OptimalTOFLabel               matlab.ui.control.Label
        OptimalDeltaVLabel            matlab.ui.control.Label
        OpTransferRadiusEditField     matlab.ui.control.EditField
        OptimalTransferRadiusLabel    matlab.ui.control.Label
        SiteInformationLabel          matlab.ui.control.Label
        ElevationEditField_3          matlab.ui.control.EditField
        ElevationLabel_3              matlab.ui.control.Label
        LongitudeEditField_3          matlab.ui.control.EditField
        LongitudeLabel_3              matlab.ui.control.Label
        LatitudeEditField_3           matlab.ui.control.EditField
        LatitudeLabel_3               matlab.ui.control.Label
    end

    
    properties (Access = public)
        FileInputPath = "";
        vehid;
        R;
        V;
        GST;
        coeData;
        % Description
    end

    methods (Access = public)
        function updateCOEs(app)
            app.VehicleIDEditField.Value = int2str(app.vehid);
            app.aEditField.Value = app.coeData{1};
            app.eEditField.Value = app.coeData{2};
            app.iEditField.Value = app.coeData{3};
            app.omegaEditField.Value = app.coeData{4};
            app.OMEGAEditField.Value = app.coeData{5};
            app.vEditField.Value = app.coeData{6};
            app.uEditField.Value = app.coeData{7};
            app.piEditField.Value = app.coeData{8};
            app.lEditField.Value = app.coeData{9};
            app.OrbitEditField.Value = app.coeData{10};
        end
        
        function populateFileOutput(app)
            try
                siteInfo = siteInfoGet(app.R, str2num(app.TOFoptionalEditField.Value), app.GST);
                app.SiteEditField.Value = siteInfo{1};
                app.LatitudeEditField_2.Value = siteInfo{2};
                app.LongitudeEditField_2.Value = siteInfo{3};
                app.AltitudeEditField_2.Value = siteInfo{4};
                app.RangeEditField_2.Value = siteInfo{5};
                app.AzimuthEditField_2.Value = siteInfo{6};
                app.ElevationEditField_2.Value = siteInfo{7};
            catch
                disp("Catch");
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            folder_path = 'BackEnd';
            addpath(genpath(folder_path), '-end');
            % manual input
            siteName = app.SiteNameEditField.Value;
            longitude = app.LongitudeEditField.Value*(pi/180);
            latitude = app.LatitudeEditField.Value*(pi/180);
            altitude = app.AltitudeEditField.Value;
            rho = app.RangeEditField.Value;
            el = app.ElevationEditField.Value*(pi/180);
            az = app.AzimuthEditField.Value*(pi/180);
            drho = app.RangeChangeEditField.Value;
            del = app.ElevationChangeEditField.Value*(pi/180);
            daz = app.AzimuthChangeEditField.Value*(pi/180);
            gst = app.GSTEditField.Value*(pi/180)*15;
            tof = app.TOFEditField.Value*(24*3600);
            LST = gst+longitude;
            try
                fid = fopen("output.txt", "w");
                if app.SiteNameEditField.Value == ""
                    fprintf(fid, "Site Name: No Site Name Given\n");
                else
                    fprintf(fid, "Site Name: %s\n", app.SiteNameEditField.Value);
                end
                
                fclose(fid);
                [R_vector,V_vector] = findingVectors(gst, latitude, longitude, altitude, rho, el, az, LST, drho, del, daz, tof);
                app.coeData = orbitCalc(R_vector, V_vector);
                app.updateCOEs();
                app.populateFileOutput();
                      
            catch
                warnMessage = "The values entered are not compatible";
                title = "Not enough outputs";
                uiconfirm(app.UIFigure, warnMessage, title, 'Icon', 'warning');
            end

            fileContents = fileread('output.txt');
            app.TextArea.Value = fileContents;
        end

        % Callback function
        function BackButton_2Pushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'Close';
            quest = 'Do you wish to close and return to Manual/File Input Window?';
            message = questdlg(quest,'Warning','Close','Cancel',opts);
            switch message
                case 'Close'
                    closereq;
                    app.ManualFile_Input=ManualFile_Input();
                case 'Cancel'
            end
        end

        % Callback function
        function BackButtonPushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'Close';
            quest = 'Do you wish to close and return to Manual/File Input Window?';
            message = questdlg(quest,'Warning','Close','Cancel',opts);
            switch message
                case 'Close'
                    closereq;
                    app.ManualFile_Input=ManualFile_Input();
                case 'Cancel'

            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,pathname]=uigetfile('*.');
            if isequal(filename,0)
                
            else
                app.FileInputPath = fullfile(pathname, filename);
                app.FileInputEditField.Value = app.FileInputPath;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            folder_path = 'BackEnd';
            addpath(genpath(folder_path), '-end');
            [opened, vehID, GST_read, date_time, R_vec, V_vec, year, month_num, day, hour, minute, seconds] = readFile(app.FileInputEditField.Value);
            app.vehid = vehID;
            app.R = R_vec;
            app.V = V_vec;
            app.GST = GST_read;
            if opened == 0
                % open gui that says 'Invalid file format or file is
                % already opened'
                message = 'Invalid file format or file is already opened';
                title = 'Invalid File';
                uiconfirm(app.UIFigure, message, title, 'Icon', 'error');
            else
                % open gui that diplays outputs
                tMatrix = inputToTOD(year, month_num, day, hour, minute, seconds);
                R_vec = tMatrix*R_vec';
                V_vec = tMatrix*V_vec';
                R_vec = R_vec';
                V_vec = V_vec';
                app.coeData = orbitCalc(R_vec, V_vec);
                app.updateCOEs();
                app.TimeEditField.Value = date_time;
                fID = fopen('output.txt','w');
                fprintf(fID,' ');
                try
                    orbitCalculations(GST_read, 38.8199*pi/180, -104.7004*pi/180, 1.875, R_vec, V_vec, str2num(app.TOFoptionalEditField.Value))
                catch
                    orbitCalculations(GST_read, 38.8199*pi/180, -104.7004*pi/180, 1.875, R_vec, V_vec, 0)
                end
                fclose(fID);
            end
            fileContents = fileread('output.txt');
            app.TextArea.Value = fileContents;

            % change values of 'edit fields
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('*.txt', 'Save file as...');
            if isequal(file, 0) || isequal(path, 0)
                return
            else
                filePath = fullfile(path,file);
            end
            saveCOEs(filePath, app.coeData);
        end

        % Button pushed function: ExitButton_2
        function ExitButton_2Pushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'Close';
            quest = 'Do you wish to exit the program?';
            message = questdlg(quest,'Warning','Close','Cancel',opts);
            
            switch message
                case 'Close'
                    closereq;
                    quit;
                case 'Cancel'

            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            opts.Interpreter = 'tex';
            opts.Default = 'Close';
            quest = 'Do you wish to exit the program?';
            message = questdlg(quest,'Warning','Close','Cancel',opts);
            
            switch message
                case 'Close'
                    closereq;
                    quit;
                case 'Cancel'

            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            [file, path] = uiputfile('*.txt', 'Save file as...');
            if isequal(file, 0) || isequal(path, 0)
                return
            else
                filePath = fullfile(path,file);
            end
            fileContents = fileread('output.txt');
            fid = fopen(filePath, 'w');
            fprintf(fid, '%s', fileContents);
            fclose(fid);
        end

        % Button pushed function: DetermineButton
        function DetermineButtonPushed(app, event)
            folder_path = 'BackEnd';
            addpath(genpath(folder_path), '-end');
            R_vector = app.R;
            V_vector = app.V;
            ui = app.coeData{7};
            vi = app.coeData{6};
            inclination = str2double(app.coeData{3});
            Radius2 = [];
            Radius2 = app.TransferOrbitRadiusEditField.Value;
            if ui ~= "NaN"
                timeToLaunch = whenToLaunch(norm(R_vector),str2num(ui));
            else
                timeToLaunch = whenToLaunch(norm(R_vector),str2num(vi));
            end
            try
                if app.HohmannButton.Value == 1
                    [deltaVTotal,TOF] = deltaV1(norm(V_vector), norm(R_vector), Radius2,inclination);
                else
                    [deltaVTotal,TOF] = deltaV4(norm(V_vector),norm(R_vector),Radius2,inclination,semiMajorAxis);
                end
                app.TOFEditField_4.Value = sprintf('%.2f',TOF);
                app.DeltaVEditField_3.Value = sprintf('%.2f',deltaVTotal);
                app.WaitTimeEditField_3.Value = sprintf('%.2f',timeToLaunch);
            catch

            end
            if app.HohmannButton.Value == 1
                [minDeltaV,corrRadius,corrTOF,minTOF,corrRadius2,corrDeltaV] = deltaV2(norm(V_vector), norm(R_vector), inclination);
            else
                [minDeltaV,corrRadius,corrTOF,minTOF,corrRadius2,corrDeltaV] = deltaV3(norm(V_vector),norm(R_vector),inclination,str2num(app.coeData{1}));
            end

            app.TOFEditField_2.Value = sprintf('%.2f',corrTOF);
            app.WaitTimeEditField.Value = sprintf('%.2f',timeToLaunch);
            app.WaitTimeEditField_2.Value = sprintf('%.2f',timeToLaunch);
            app.DeltaVEditField.Value = sprintf('%.2f',minDeltaV);
            app.OpTransferRadiusEditField.Value = sprintf('%.2f',corrRadius);
            app.OpTransferRadiusEditField_2.Value = sprintf('%.2f',corrRadius2);
            app.TOFEditField_3.Value = sprintf('%.2f',minTOF);
            app.DeltaVEditField_2.Value = sprintf('%.2f',corrDeltaV);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualInputTab = uitab(app.TabGroup);
            app.ManualInputTab.Title = 'Manual Input';
            app.RunButton = uibutton(app.ManualInputTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LongitudeLabel = uilabel(app.ManualInputTab);
            app.LongitudeLabel.Text = 'Longitude:';
            app.LongitudeEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.LatitudeLabel = uilabel(app.ManualInputTab);
            app.LatitudeLabel.Text = 'Latitude:';
            app.LatitudeEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.AltitudeLabel = uilabel(app.ManualInputTab);
            app.AltitudeLabel.Text = 'Altitude:';
            app.AltitudeEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.RangeChangeLabel = uilabel(app.ManualInputTab);
            app.RangeChangeLabel.Text = 'Range Change:';
            app.RangeChangeEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.AzimuthLabel = uilabel(app.ManualInputTab);
            app.AzimuthLabel.Text = 'Azimuth:';
            app.AzimuthEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.ElevationChangeLabel = uilabel(app.ManualInputTab);
            app.ElevationChangeLabel.Text = 'Elevation Change:';
            app.ElevationChangeEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.ElevationLabel = uilabel(app.ManualInputTab);
            app.ElevationLabel.Text = 'Elevation:';
            app.ElevationEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.RangeLabel = uilabel(app.ManualInputTab);
            app.RangeLabel.Text = 'Range:';
            app.RangeEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.AzimuthChangeLabel = uilabel(app.ManualInputTab);
            app.AzimuthChangeLabel.Text = 'Azimuth Change:';
            app.AzimuthChangeEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.SiteNameLabel = uilabel(app.ManualInputTab);
            app.SiteNameLabel.Text = 'Site Name:';
            app.SiteNameEditField = uieditfield(app.ManualInputTab, 'text');
            app.GSTLabel = uilabel(app.ManualInputTab);
            app.GSTLabel.Text = 'GST:';
            app.GSTEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.TOFLabel = uilabel(app.ManualInputTab);
            app.TOFLabel.Text = 'TOF:';
            app.TOFEditField = uieditfield(app.ManualInputTab, 'numeric');
            app.ExitButton_2 = uibutton(app.ManualInputTab, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_2Pushed, true);
            app.ExitButton_2.Text = 'Exit';
            app.degreesLabel_12 = uilabel(app.ManualInputTab);
            app.degreesLabel_12.Text = 'degrees';
            app.degreesLabel_13 = uilabel(app.ManualInputTab);
            app.degreesLabel_13.Text = 'degrees';
            app.degreesLabel_14 = uilabel(app.ManualInputTab);
            app.degreesLabel_14.Text = 'degrees';
            app.degreesLabel_15 = uilabel(app.ManualInputTab);
            app.degreesLabel_15.Text = 'degrees';
            app.kmLabel_4 = uilabel(app.ManualInputTab);
            app.kmLabel_4.Text = 'km';
            app.kmLabel_5 = uilabel(app.ManualInputTab);
            app.kmLabel_5.Text = 'km';
            app.kmsLabel = uilabel(app.ManualInputTab);
            app.kmsLabel.Text = 'km/s';
            app.degreessLabel = uilabel(app.ManualInputTab);
            app.degreessLabel.Text = 'degrees/s';
            app.hrsLabel = uilabel(app.ManualInputTab);
            app.hrsLabel.Text = 'hrs';
            app.daysLabel = uilabel(app.ManualInputTab);
            app.daysLabel.Text = 'days';
            app.degreessLabel_2 = uilabel(app.ManualInputTab);
            app.degreessLabel_2.Text = 'degrees/s';
            app.FileInputTab = uitab(app.TabGroup);
            app.FileInputTab.Title = 'File Input';
            app.kmLabel = uilabel(app.FileInputTab);
            app.kmLabel.Text = 'km';
            app.degreesLabel = uilabel(app.FileInputTab);
            app.degreesLabel.Text = 'degrees';
            app.degreesLabel_2 = uilabel(app.FileInputTab);
            app.degreesLabel_2.Text = 'degrees';
            app.degreesLabel_3 = uilabel(app.FileInputTab);
            app.degreesLabel_3.Text = 'degrees';
            app.degreesLabel_4 = uilabel(app.FileInputTab);
            app.degreesLabel_4.Text = 'degrees';
            app.degreesLabel_5 = uilabel(app.FileInputTab);
            app.degreesLabel_5.Text = 'degrees';
            app.degreesLabel_6 = uilabel(app.FileInputTab);
            app.degreesLabel_6.Text = 'degrees';
            app.degreesLabel_7 = uilabel(app.FileInputTab);
            app.degreesLabel_7.Text = 'degrees';
            app.ExitButton = uibutton(app.FileInputTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveButton = uibutton(app.FileInputTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BrowseButton = uibutton(app.FileInputTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CalculateButton = uibutton(app.FileInputTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PressCalculatetoDispalyOutputLabel = uilabel(app.FileInputTab);
            app.PressCalculatetoDispalyOutputLabel.Text = 'Press "Calculate" to Dispaly Output';
            app.VehicleIDEditFieldLabel = uilabel(app.FileInputTab);
            app.VehicleIDEditFieldLabel.Text = 'Vehicle ID:';
            app.VehicleIDEditField = uieditfield(app.FileInputTab, 'text');
            app.OrbitEditFieldLabel = uilabel(app.FileInputTab);
            app.OrbitEditFieldLabel.Text = 'Orbit:';
            app.OrbitEditField = uieditfield(app.FileInputTab, 'text');
            app.FileInputLabel = uilabel(app.FileInputTab);
            app.FileInputLabel.Text = 'File Input:';
            app.FileInputEditField = uieditfield(app.FileInputTab, 'text');
            app.aEditFieldLabel = uilabel(app.FileInputTab);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.FileInputTab, 'text');
            app.eEditFieldLabel = uilabel(app.FileInputTab);
            app.eEditFieldLabel.Text = 'e:';
            app.eEditField = uieditfield(app.FileInputTab, 'text');
            app.iEditFieldLabel = uilabel(app.FileInputTab);
            app.iEditFieldLabel.Text = 'i:';
            app.iEditField = uieditfield(app.FileInputTab, 'text');
            app.omegaEditFieldLabel = uilabel(app.FileInputTab);
            app.omegaEditFieldLabel.Text = 'omega:';
            app.omegaEditField = uieditfield(app.FileInputTab, 'text');
            app.OMEGAEditFieldLabel = uilabel(app.FileInputTab);
            app.OMEGAEditFieldLabel.Text = 'OMEGA:';
            app.OMEGAEditField = uieditfield(app.FileInputTab, 'text');
            app.vEditFieldLabel = uilabel(app.FileInputTab);
            app.vEditFieldLabel.Text = 'v:';
            app.vEditField = uieditfield(app.FileInputTab, 'text');
            app.uEditFieldLabel = uilabel(app.FileInputTab);
            app.uEditFieldLabel.Text = 'u:';
            app.uEditField = uieditfield(app.FileInputTab, 'text');
            app.piEditFieldLabel = uilabel(app.FileInputTab);
            app.piEditFieldLabel.Text = 'pi:';
            app.piEditField = uieditfield(app.FileInputTab, 'text');
            app.lEditFieldLabel = uilabel(app.FileInputTab);
            app.lEditFieldLabel.Text = 'l:';
            app.lEditField = uieditfield(app.FileInputTab, 'text');
            app.TOFoptionalLabel = uilabel(app.FileInputTab);
            app.TOFoptionalLabel.Text = 'TOF (optional):';
            app.TOFoptionalEditField = uieditfield(app.FileInputTab, 'text');
            app.TOFoptionalEditField.InputType = 'digits';
            app.secondsLabel = uilabel(app.FileInputTab);
            app.secondsLabel.Text = 'seconds';
            app.TimeLabel = uilabel(app.FileInputTab);
            app.TimeLabel.Text = 'Time:';
            app.TimeEditField = uieditfield(app.FileInputTab, 'text');
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.TextArea = uitextarea(app.OutputTab);
            app.SaveButton_2 = uibutton(app.OutputTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.OrbitManeuverTab = uitab(app.TabGroup);
            app.OrbitManeuverTab.Title = 'Orbit Maneuver';
            app.LatitudeLabel_3 = uilabel(app.OrbitManeuverTab);
            app.LatitudeLabel_3.Text = 'Latitude:';
            app.LatitudeEditField_3 = uieditfield(app.OrbitManeuverTab, 'text');
            app.LongitudeLabel_3 = uilabel(app.OrbitManeuverTab);
            app.LongitudeLabel_3.Text = 'Longitude:';
            app.LongitudeEditField_3 = uieditfield(app.OrbitManeuverTab, 'text');
            app.ElevationLabel_3 = uilabel(app.OrbitManeuverTab);
            app.ElevationLabel_3.Text = 'Elevation:';
            app.ElevationEditField_3 = uieditfield(app.OrbitManeuverTab, 'text');
            app.SiteInformationLabel = uilabel(app.OrbitManeuverTab);
            app.SiteInformationLabel.Text = 'Site Information';
            app.Panel = uipanel(app.OrbitManeuverTab);
            app.Panel.Title = 'Panel';
            app.OptimalTransferRadiusLabel = uilabel(app.Panel);
            app.OptimalTransferRadiusLabel.Text = 'Op Transfer Radius:';
            app.OpTransferRadiusEditField = uieditfield(app.Panel, 'text');
            app.OptimalDeltaVLabel = uilabel(app.Panel);
            app.OptimalDeltaVLabel.Text = 'Optimal Delta V';
            app.OptimalTOFLabel = uilabel(app.Panel);
            app.OptimalTOFLabel.Text = 'Optimal TOF';
            app.UserConstrainedLabel = uilabel(app.Panel);
            app.UserConstrainedLabel.Text = 'User Constrained';
            app.TOFLabel_2 = uilabel(app.Panel);
            app.TOFLabel_2.Text = 'TOF:';
            app.TOFEditField_2 = uieditfield(app.Panel, 'text');
            app.DeltaVLabel = uilabel(app.Panel);
            app.DeltaVLabel.Text = 'Delta V:';
            app.DeltaVEditField = uieditfield(app.Panel, 'text');
            app.TOFLabel_3 = uilabel(app.Panel);
            app.TOFLabel_3.Text = 'TOF:';
            app.TOFEditField_3 = uieditfield(app.Panel, 'text');
            app.DeltaVLabel_2 = uilabel(app.Panel);
            app.DeltaVLabel_2.Text = 'Delta V:';
            app.DeltaVEditField_2 = uieditfield(app.Panel, 'text');
            app.WaitTimeLabel = uilabel(app.Panel);
            app.WaitTimeLabel.Text = 'Wait Time:';
            app.WaitTimeEditField = uieditfield(app.Panel, 'text');
            app.WaitTimeLabel_2 = uilabel(app.Panel);
            app.WaitTimeLabel_2.Text = 'Wait Time:';
            app.WaitTimeEditField_2 = uieditfield(app.Panel, 'text');
            app.OptimalTransferRadiusLabel_2 = uilabel(app.Panel);
            app.OptimalTransferRadiusLabel_2.Text = 'Op Transfer Radius:';
            app.OpTransferRadiusEditField_2 = uieditfield(app.Panel, 'text');
            app.TOFLabel_4 = uilabel(app.Panel);
            app.TOFLabel_4.Text = 'TOF:';
            app.TOFEditField_4 = uieditfield(app.Panel, 'text');
            app.DeltaVLabel_3 = uilabel(app.Panel);
            app.DeltaVLabel_3.Text = 'Delta V:';
            app.DeltaVEditField_3 = uieditfield(app.Panel, 'text');
            app.WaitTimeLabel_3 = uilabel(app.Panel);
            app.WaitTimeLabel_3.Text = 'Wait Time:';
            app.WaitTimeEditField_3 = uieditfield(app.Panel, 'text');
            app.OrbitTransferOptionsButtonGroup = uibuttongroup(app.Panel);
            app.OrbitTransferOptionsButtonGroup.Title = 'Orbit Transfer Options';
            app.HohmannButton = uiradiobutton(app.OrbitTransferOptionsButtonGroup);
            app.HohmannButton.Text = 'Hohmann';
            app.HohmannButton.Value = true;
            app.EllipticButton = uiradiobutton(app.OrbitTransferOptionsButtonGroup);
            app.EllipticButton.Text = 'Elliptic';
            app.hrsLabel_2 = uilabel(app.Panel);
            app.hrsLabel_2.Text = 'hrs';
            app.kmsLabel_2 = uilabel(app.Panel);
            app.kmsLabel_2.Text = 'km/s';
            app.hrsLabel_3 = uilabel(app.Panel);
            app.hrsLabel_3.Text = 'hrs';
            app.hrsLabel_4 = uilabel(app.Panel);
            app.hrsLabel_4.Text = 'hrs';
            app.kmsLabel_3 = uilabel(app.Panel);
            app.kmsLabel_3.Text = 'km/s';
            app.hrsLabel_5 = uilabel(app.Panel);
            app.hrsLabel_5.Text = 'hrs';
            app.kmLabel_6 = uilabel(app.Panel);
            app.kmLabel_6.Text = 'km';
            app.kmLabel_7 = uilabel(app.Panel);
            app.kmLabel_7.Text = 'km';
            app.hrsLabel_6 = uilabel(app.Panel);
            app.hrsLabel_6.Text = 'hrs';
            app.kmsLabel_4 = uilabel(app.Panel);
            app.kmsLabel_4.Text = 'km/s';
            app.hrsLabel_7 = uilabel(app.Panel);
            app.hrsLabel_7.Text = 'hrs';
            app.DetermineButton = uibutton(app.OrbitManeuverTab, 'push');
            app.DetermineButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineButtonPushed, true);
            app.DetermineButton.Text = 'Determine';
            app.TransferOrbitRadiusEditFieldLabel = uilabel(app.OrbitManeuverTab);
            app.TransferOrbitRadiusEditFieldLabel.Text = 'Transfer Orbit Radius';
            app.TransferOrbitRadiusEditField = uieditfield(app.OrbitManeuverTab, 'numeric');
            app.degreesLabel_16 = uilabel(app.OrbitManeuverTab);
            app.degreesLabel_16.Text = 'degrees';
            app.degreesLabel_17 = uilabel(app.OrbitManeuverTab);
            app.degreesLabel_17.Text = 'degrees';
            app.kmLabel_8 = uilabel(app.OrbitManeuverTab);
            app.kmLabel_8.Text = 'km';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manual_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end